classdef ProyectoFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Graph                         matlab.ui.control.UIAxes
        RunButton                     matlab.ui.control.StateButton
        LongitudBrazo1EditFieldLabel  matlab.ui.control.Label
        L1                            matlab.ui.control.NumericEditField
        LongitudBrazo2EditFieldLabel  matlab.ui.control.Label
        L2                            matlab.ui.control.NumericEditField
        LongitudBrazo3EditFieldLabel  matlab.ui.control.Label
        L3                            matlab.ui.control.NumericEditField
        Coordinates                   matlab.ui.control.ListBox
        AgregarButton                 matlab.ui.control.StateButton
        ZonadeTrabajoSwitchLabel      matlab.ui.control.Label
        ZonadeTrabajoSwitch           matlab.ui.control.Switch
        ErrorEditFieldLabel           matlab.ui.control.Label
        Error                         matlab.ui.control.NumericEditField
        PasosEditFieldLabel           matlab.ui.control.Label
        PasosEditField                matlab.ui.control.NumericEditField
        WaypointsEditFieldLabel       matlab.ui.control.Label
        WaypointsEditField            matlab.ui.control.EditField
    end

    
    methods (Access = public)
        
        function O = plotVector(app, Vector, Origin)
            X = [Origin(1) (Vector(1) + Origin(1))];
            Y = [Origin(2) (Vector(2) + Origin(2))];
            Z = [Origin(3) (Vector(3) + Origin(3))];
            O = plot3(app.Graph, X, Y, Z,'LineWidth', 5);
        end
        
                
        function A = rotateVector(app, Vector, xRot, yRot, zRot)
            Rz = [cos(zRot) -sin(zRot) 0; sin(zRot) cos(zRot) 0; 0 0 1];
            Ry = [cos(yRot) 0 sin(yRot); 0 1 0; -sin(yRot) 0 cos(yRot)];
            Rx = [1 0 0; 0 cos(xRot) -sin(xRot); 0 sin(xRot) cos(xRot)];
            A = Rz*Ry*Rx*Vector;
        end
        
        function [Points] = getPoints(app, startp, endp, pts, minDistance)
            t = linspace(0, 1, pts)';
            Points = (1-t)*startp + t*endp;
            minDistance = minDistance + 0.1;
            
            O = [0 0 0];
            R0 = startp;
            Rd = endp - startp;
            Rd = Rd./norm(Rd);
            % Any point is defined by T0 + Rd*t
            t = dot(-R0, Rd);
            % u is our perpendicular vector
            u = R0 + Rd*t;
            
            % When vector passes exactly through the middle
            if sum(u ~= 0) == 0
                if Rd(1) ~= 0
                    u = [0 0.001 0];
                else
                    u = [0.001 0 0];
                end
            end

            for i = 1:pts
                distance = app.calcDistance(Points(i,:), O);
                if distance < minDistance
                    R0 = Points(i,:);
                    Rd = u;
                    Rd = Rd./norm(Rd);
                    
                    % Any point is defined by T0 + Rd*t
                    t = dot(-R0, Rd);
                    % Pt is our vector towards the outer circle
                    Pt = R0 + Rd*t;
                    y = app.calcDistance(O, Pt);
                    x = sqrt(minDistance^2 - y^2);
                    Pt1 = R0 + Rd*(t - x);
                    Pt2 = R0 + Rd*(t + x);
                    
                    if app.calcDistance(Points(i,:), Pt1) < app.calcDistance(Points(i,:), Pt2)
                        Points(i,:) = Pt1;
                    else
                        Points(i,:) = Pt2;
                    end
                end
            end
        end
        
        function distance = calcDistance(app, p1, p2)
             distance = sqrt(sum((p1-p2).^2));       
        end
        
        function drawBounds(app)
            [X,Y,Z] = sphere;
            r = app.L1.Value - (app.L2.Value + app.L3.Value);
            X2 = X * r;
            Y2 = Y * r;
            Z2 = Z * r;
            
            h = surf(app.Graph, X2,Y2,Z2);
            set(h, 'FaceAlpha', 0.1)
            
            hold (app.Graph,'on')
            r = app.L1.Value + app.L2.Value + app.L3.Value;
            X2 = X * r;
            Y2 = Y * r;
            Z2 = Z * r;
            h = surf(app.Graph, X2,Y2,Z2);
            set(h, 'FaceAlpha', 0.1)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Callback function
        function SliderChange(app, event)
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            global CoordData
            
            % arm lengths
            l1 = app.L1.Value;
            l2 = app.L2.Value;
            l3 = app.L3.Value;
            
            % arm length vectors for graphics
            A = [l1; 0; 0];
            B = [l2; 0; 0];
            C = [l3; 0; 0];
            
            % This is our coordinates raw data
            coords = CoordData;
            newCoords = [];
            
            % We interpolate the coordinate to get a smoother movement
            % TODO: calculate number of interpolations
            minDistance = l1 - (l2 + l3);
            for i = 1:(size(coords, 1)-1)
               distance = app.calcDistance(coords(i,:), coords(i + 1,:));
               newCoords = [newCoords; app.getPoints(coords(i,:), coords(i + 1,:), app.PasosEditField.Value, minDistance)];
            end
            
            % initialize our Jacob Matrix and our Theta matrix
            J = [0 0 0 0 0 0; 0 0 0 0 0 0; 0 0 0 0 0 0];
            T = [0.5;0.5;0.5;0.5;0.5;0.5];
            
            % All of our coordinates including interpolated coords
            for coord = 1:size(newCoords, 1)
                % Get the point from the matrix of coordinates
                P = [newCoords(coord, 1);
                     newCoords(coord, 2);
                     newCoords(coord, 3)];
                
                % Our base functions
                % These functions return x, y, z respectively with the 6
                % thetas provided
                fx = @(t1, t2, t3, t4, t5, t6) l1*cos(t1)*cos(t2) + l2*cos(t1+t3)*cos(t2+t4) + l3*cos(t1+t3+t5)*cos(t2+t4+t6) - P(1);
                fy = @(t1, t2, t3, t4, t5, t6) l1*cos(t1)*sin(t2) + l2*cos(t1+t3)*sin(t2+t4) + l3*cos(t1+t3+t5)*sin(t2+t4+t6) - P(2);
                fz = @(t1, t2, t3, t4, t5, t6) l1*sin(t1) + l2*sin(t1+t3) + l3*sin(t1+t3+t5) - P(3);
                
                % Here we define our derivative functions
                % These are the derivative functions for x, for each theta
                fdt1x = @(t1, t2, t3, t4, t5, t6) -l1*sin(t1)*cos(t2) - l2*sin(t1+t3)*cos(t2+t4) - l3*sin(t1+t3+t5)*cos(t2+t4+t6);
                fdt2x = @(t1, t2, t3, t4, t5, t6) -l1*cos(t1)*sin(t2) - l2*cos(t1+t3)*sin(t2+t4) - l3*cos(t1+t3+t5)*sin(t2+t4+t6);
                fdt3x = @(t1, t2, t3, t4, t5, t6) -l2*sin(t1+t3)*cos(t2+t4) - l3*sin(t1+t3+t5)*cos(t2+t4+t6);
                fdt4x = @(t1, t2, t3, t4, t5, t6) -l2*cos(t1+t3)*sin(t2+t4) - l3*cos(t1+t3+t5)*sin(t2+t4+t6);
                fdt5x = @(t1, t2, t3, t4, t5, t6) -l3*sin(t1+t3+t5)*cos(t2+t4+t6);
                fdt6x = @(t1, t2, t3, t4, t5, t6) -l3*cos(t1+t3+t5)*sin(t2+t4+t6);
                
                % These are the derivative functions for y, for each theta
                fdt1y = @(t1, t2, t3, t4, t5, t6) -l1*sin(t1)*sin(t2) - l2*sin(t1+t3)*sin(t2+t4) - l3*sin(t1+t3+t5)*sin(t2+t4+t6);
                fdt2y = @(t1, t2, t3, t4, t5, t6)  l1*cos(t1)*cos(t2) + l2*cos(t1+t3)*cos(t2+t4) + l3*cos(t1+t3+t5)*cos(t2+t4+t6);
                fdt3y = @(t1, t2, t3, t4, t5, t6) -l2*sin(t1+t3)*sin(t2+t4) - l3*sin(t1+t3+t5)*sin(t2+t4+t6);
                fdt4y = @(t1, t2, t3, t4, t5, t6)  l2*cos(t1+t3)*cos(t2+t4) + l3*cos(t1+t3+t5)*cos(t2+t4+t6);
                fdt5y = @(t1, t2, t3, t4, t5, t6) -l3*sin(t1+t3+t5)*sin(t2+t4+t6);
                fdt6y = @(t1, t2, t3, t4, t5, t6)  l3*cos(t1+t3+t5)*cos(t2+t4+t6);
                
                % These are the derivative functions for z, for each theta
                fdt1z = @(t1, t2, t3, t4, t5, t6) l1*cos(t1) + l2*cos(t1+t3) + l3*cos(t1+t3+t5);
                fdt2z = @(t1, t2, t3, t4, t5, t6) 0;
                fdt3z = @(t1, t2, t3, t4, t5, t6) l2*cos(t1+t3) + l3*cos(t1+t3+t5);
                fdt4z = @(t1, t2, t3, t4, t5, t6) 0;
                fdt5z = @(t1, t2, t3, t4, t5, t6) l3*cos(t1+t3+t5);
                fdt6z = @(t1, t2, t3, t4, t5, t6) 0;
                
                % Since we can't use a matrix to store function handlers,
                % we instead use 3 cell arrays, one for x, one for y and
                % one for z
                JFx = {fdt1x fdt2x fdt3x fdt4x fdt5x fdt6x};
                JFy = {fdt1y fdt2y fdt3y fdt4y fdt5y fdt6y}; 
                JFz = {fdt1z fdt2z fdt3z fdt4z fdt5z fdt6z};
                
                % We iterate the number of iterations to get close to our
                % desired point
                error = 100;
                while error > app.Error.Value
                    
                    % We calculate the Jacobian Matrix using our function
                    % handlers
                    % Iterate 6 times because we have 6 angles
                    for i = 1:6
                        J(1, i) = JFx{i}(T(1), T(2), T(3), T(4), T(5), T(6));
                        J(2, i) = JFy{i}(T(1), T(2), T(3), T(4), T(5), T(6));
                        J(3, i) = JFz{i}(T(1), T(2), T(3), T(4), T(5), T(6));
                    end
                    
                    % We evaluate our thetas
                    F = [fx(T(1), T(2), T(3), T(4), T(5), T(6));
                         fy(T(1), T(2), T(3), T(4), T(5), T(6));
                         fz(T(1), T(2), T(3), T(4), T(5), T(6))];
                     
                    % We use NR to calculate the new thetas with the
                    % Jacobian Matrix, thetas evaluated and the old thetas
                    T = T - J\F;
                    
                    error = mean(F);
                end
                
                % Clear the current Graph
                cla(app.Graph)
                hold (app.Graph,'on')
                
                if app.ZonadeTrabajoSwitch.Value == 'On'
                    app.drawBounds();
                end
                
                % Our origin is (0, 0, 0)
                Origin = [0; 0; 0];
                
                % Calculate and plot arm #1
                RA = rotateVector(app, A, 0, -(T(1)), T(2));
                HA = app.plotVector(RA, Origin);
            
                % Calculate and plot arm #2
                RB = rotateVector(app, B, 0, -(T(1) + T(3)), T(2) + T(4));
                HB = app.plotVector(RB, RA);
            
                % Calculate and plot arm #3
                RC = rotateVector(app, C, 0, -(T(1) + T(3) + T(5)), T(2) + T(4) + T(6));
                HC = app.plotVector(RC, RA + RB);
                
                % This is just to get a better view of the end point, it's
                % a small sphere with a radius of 1
                [X,Y,Z] = sphere;
                r = 0.1;
                X2 = X * r;
                Y2 = Y * r;
                Z2 = Z * r;
                
                F = RA + RB + RC;
                surf(app.Graph, X2+F(1),Y2+F(2),Z2+F(3))
%                 surf(app.Graph, X2,Y2,Z2)
                fill3(app.Graph, [-0.05 -0.05 0.05 0.05],[-0.05 0.05 0.05 -0.05],[-0.05 -0.05 -0.05 -0.05],'b', ...
                                     [-0.05 -0.05 0.05 0.05],[-0.05 0.05 0.05 -0.05],[0.05 0.05 0.05 0.05],'b', ...
                                     [-0.05 -0.05 -0.05 -0.05],[-0.05 0.05 0.05 -0.05],[-0.05 -0.05 0.05 0.05],'b', ...
                                     [0.05 0.05 0.05 0.05],[-0.05 0.05 0.05 -0.05],[-0.05 -0.05 0.05 0.05],'b', ...
                                     [-0.05 -0.05 0.05 0.05],[-0.05 -0.05 -0.05 -0.05],[-0.05 0.05 0.05 -0.05],'b',...
                                     [-0.05 -0.05 0.05 0.05],[0.05 0.05 0.05 0.05],[-0.05 0.05 0.05 -0.05],'b') ;
                
                for point = 0:1:(coord-1)
                    scatter3(app.Graph, newCoords(point+1, 1), newCoords(point+1, 2), newCoords(point+1, 3));
                end
                
                rt1 = T(1);
                rt2 = T(2);
                rt3 = rt1+T(3);
                rt4 = rt2+T(4);
                rt5 = rt3+T(5);
                rt6 = rt4+T(6);
                
                % Pause for 0.1s until our next iteration
                % TODO: slider for this?
                pause(0.05)
            end
        end

        % Value changed function: AgregarButton
        function AgregarButtonValueChanged(app, event)
            if isempty(app.Coordinates.Items)
                clear global CoordData;
            end
            
            global CoordData
            mat = app.WaypointsEditField.Value;
            a = erase(mat,{'[', ']'});
            mat = str2num(a);
            
            [rows, columns] = size(mat);
            
            data = [0, 0, app.L1.Value + app.L2.Value + app.L3.Value];
            CoordData = [CoordData; data];
            
            for i = 1:rows
                xVal = mat(i, 1);
                yVal = mat(i, 2);
                zVal = mat(i, 3);
                
                distance = app.calcDistance([0;0;0], [xVal; yVal; zVal]);
                maxDistance = app.L1.Value + app.L2.Value + app.L3.Value;
                minDistance = app.L1.Value - (app.L2.Value + app.L3.Value);
                
                if distance > maxDistance || distance < minDistance
                    msgbox({'Invalid Value','Punto inalcanzable'}, 'Error','error');
                else
                    data = [xVal yVal zVal];
                    %app.Coordinates.Items = [app.Coordinates.Items, str];
                    if exist('CoordData','var') == 0
                        CoordData = [];
                    end
                    CoordData = [CoordData; data];
                end
            end
            
            data = [0, 0, app.L1.Value + app.L2.Value + app.L3.Value];
            CoordData = [CoordData; data];
            
            %str = ['(' num2str(app.XField.Value) ',' num2str(app.YField.Value) ',' num2str(app.ZField.Value) ')'];
            %distance = app.calcDistance([0;0;0], [app.XField.Value;app.YField.Value;app.ZField.Value]);
            %maxDistance = app.L1.Value + app.L2.Value + app.L3.Value;
            %minDistance = app.L1.Value - (app.L2.Value + app.L3.Value);
            
            %if distance > maxDistance || distance < minDistance
             %   msgbox({'Invalid Value','Punto inalcanzable'}, 'Error','error');
            %else
             %   data = [app.XField.Value app.YField.Value app.ZField.Value];
              %  app.Coordinates.Items = [app.Coordinates.Items, str];
               % if exist('CoordData','var') == 0
                %    CoordData = [];
                %end
                %CoordData = [CoordData; data];
            %end
        end

        % Callback function
        function BorrarButtonValueChanged(app, event)
            global CoordData
            value = app.Coordinates.Value;
            values = app.Coordinates.Items;
            index = -1;
            for i = 1:size(values, 1)
                if  ismember(value, values(i,1))
                    index = i;
                end
            end
            
            if index ~= -1
                values(index)=[];
                CoordData(index)=[];
                app.Coordinates.Items = values;
            end
        end

        % Value changed function: ZonadeTrabajoSwitch
        function ZonadeTrabajoSwitchValueChanged(app, event)
            app.drawBounds();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Title')
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            app.Graph.XLim = [-10 10];
            app.Graph.YLim = [-10 10];
            app.Graph.ZLim = [-10 10];
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.LongitudBrazo1EditFieldLabel = uilabel(app.UIFigure);
            app.LongitudBrazo1EditFieldLabel.Text = 'Longitud Brazo 1';
            app.L1 = uieditfield(app.UIFigure, 'numeric');
            app.L1.Value = 2;
            app.LongitudBrazo2EditFieldLabel = uilabel(app.UIFigure);
            app.LongitudBrazo2EditFieldLabel.Text = 'Longitud Brazo 2';
            app.L2 = uieditfield(app.UIFigure, 'numeric');
            app.L2.Value = 2;
            app.LongitudBrazo3EditFieldLabel = uilabel(app.UIFigure);
            app.LongitudBrazo3EditFieldLabel.Text = 'Longitud Brazo 3';
            app.L3 = uieditfield(app.UIFigure, 'numeric');
            app.L3.Value = 2;
            app.Coordinates = uilistbox(app.UIFigure);
            app.Coordinates.Items = {};
            app.Coordinates.Value = {};
            app.AgregarButton = uibutton(app.UIFigure, 'state');
            app.AgregarButton.ValueChangedFcn = createCallbackFcn(app, @AgregarButtonValueChanged, true);
            app.AgregarButton.Text = 'Agregar';
            app.ZonadeTrabajoSwitchLabel = uilabel(app.UIFigure);
            app.ZonadeTrabajoSwitchLabel.Text = 'Zona de Trabajo';
            app.ZonadeTrabajoSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZonadeTrabajoSwitch.ValueChangedFcn = createCallbackFcn(app, @ZonadeTrabajoSwitchValueChanged, true);
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.Error = uieditfield(app.UIFigure, 'numeric');
            app.Error.Value = 0.01;
            app.PasosEditFieldLabel = uilabel(app.UIFigure);
            app.PasosEditFieldLabel.Text = 'Pasos';
            app.PasosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PasosEditField.Value = 10;
            app.WaypointsEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointsEditFieldLabel.Text = 'Waypoints';
            app.WaypointsEditField = uieditfield(app.UIFigure, 'text');
            app.WaypointsEditField.Value = '[2 2 2; 2 -2 2; 2 -2 -2]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end